classdef Rob_4R_Planar_Mechanism < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        Inverse_Frame            matlab.ui.container.Panel
        InverseTitle             matlab.ui.control.TextArea
        Inverse_BackButton       matlab.ui.control.Button
        CalculateInverse         matlab.ui.control.Button
        Inverse_theta2           matlab.ui.control.NumericEditField
        Inverse_theta3           matlab.ui.control.NumericEditField
        Inverse_theta4           matlab.ui.control.NumericEditField
        Joint_Angles             matlab.ui.control.TextArea
        Inverse_Plot             matlab.ui.control.Button
        Inverse_theta1           matlab.ui.control.NumericEditField
        theta1Label_2            matlab.ui.control.Label
        theta2Label_2            matlab.ui.control.Label
        theta3Label_2            matlab.ui.control.Label
        theta4Label_2            matlab.ui.control.Label
        InverseAxes              matlab.ui.control.UIAxes
        Forward_Frame            matlab.ui.container.Panel
        Forward_BackButton       matlab.ui.control.Button
        ForwardTitle             matlab.ui.control.TextArea
        CalculateForward         matlab.ui.control.Button
        xEditFieldLabel          matlab.ui.control.Label
        Forward_X                matlab.ui.control.NumericEditField
        yEditFieldLabel          matlab.ui.control.Label
        Forward_Y                matlab.ui.control.NumericEditField
        zEditFieldLabel          matlab.ui.control.Label
        Forward_Z                matlab.ui.control.NumericEditField
        EndEffectorTitle         matlab.ui.control.TextArea
        Forward_Plot             matlab.ui.control.Button
        ForwardAxes              matlab.ui.control.UIAxes
        Main_Frame               matlab.ui.container.Panel
        Title                    matlab.ui.control.TextArea
        KinematicsDropDownLabel  matlab.ui.control.Label
        KinematicsDropDown       matlab.ui.control.DropDown
        Link1EditField           matlab.ui.control.NumericEditField
        Link2EditField           matlab.ui.control.NumericEditField
        Link3EditField           matlab.ui.control.NumericEditField
        Link4EditField           matlab.ui.control.NumericEditField
        DH11                     matlab.ui.control.NumericEditField
        DH12                     matlab.ui.control.NumericEditField
        DH13                     matlab.ui.control.NumericEditField
        DH14                     matlab.ui.control.NumericEditField
        DH21                     matlab.ui.control.NumericEditField
        DH22                     matlab.ui.control.NumericEditField
        DH23                     matlab.ui.control.NumericEditField
        DH24                     matlab.ui.control.NumericEditField
        DH31                     matlab.ui.control.NumericEditField
        DH32                     matlab.ui.control.NumericEditField
        DH33                     matlab.ui.control.NumericEditField
        DH34                     matlab.ui.control.NumericEditField
        DH41                     matlab.ui.control.NumericEditField
        DH42                     matlab.ui.control.NumericEditField
        DH43                     matlab.ui.control.NumericEditField
        DH44                     matlab.ui.control.NumericEditField
        Label_theta              matlab.ui.control.Label
        Label_d                  matlab.ui.control.Label
        Label_a                  matlab.ui.control.Label
        Label_alpha              matlab.ui.control.Label
        Link1Label_2             matlab.ui.control.Label
        Link2Label_2             matlab.ui.control.Label
        Link3Label_2             matlab.ui.control.Label
        Link4Label_2             matlab.ui.control.Label
        ProcessButton            matlab.ui.control.Button
        LinkLengthLabel          matlab.ui.control.Label
        DHTableLabel             matlab.ui.control.Label
        Link1Label_3             matlab.ui.control.Label
        Link2Label_3             matlab.ui.control.Label
        Link3Label_3             matlab.ui.control.Label
        Link4Label_3             matlab.ui.control.Label
        x2                       matlab.ui.control.NumericEditField
        y2                       matlab.ui.control.NumericEditField
        x4                       matlab.ui.control.NumericEditField
        y4                       matlab.ui.control.NumericEditField
        x3                       matlab.ui.control.NumericEditField
        y3                       matlab.ui.control.NumericEditField
        CoordinatesLabel         matlab.ui.control.Label
        XLabel                   matlab.ui.control.Label
        YLabel                   matlab.ui.control.Label
        x1                       matlab.ui.control.NumericEditField
        y1                       matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        l % Link Lengths
        joint_Angle % joint angles
        d % joint offset
        a % joint length ( = l)
        alpha % twist angle
        x
        y
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

%                 
%                 app.DH11.Visible="Off";
%                 app.DH12.Visible="Off";
%                 app.DH13.Visible="Off";
%                 app.DH14.Visible="Off";
%                 
%                 app.DH21.Visible="Off"
%                 app.DH22.Visible="Off"
%                 app.DH23.Visible="Off"
%                 app.DH24.Visible="Off"
%                 
%                 app.DH31.Visible="Off"
%                 app.DH32.Visible="Off"
%                 app.DH33.Visible="Off"
%                 app.DH34.Visible="Off"
%                 
%                 app.DH41.Visible="Off"
%                 app.DH42.Visible="Off"
%                 app.DH43.Visible="Off"
%                 app.DH44.Visible="Off"
%                 
%                 app.Label_alpha.Visible="Off"
%                 app.Label_theta.Visible="Off"
%                 app.Label_a.Visible="Off"
%                 app.Label_d.Visible="Off"
%                 
%                 app.Link1Label_2.Visible="Off"
%                 app.Link2Label_2.Visible="Off"
%                 app.Link3Label_2.Visible="Off"
%                 app.Link4Label_2.Visible="Off"
%             
 app.l=[0;0;0;0;];
 app.joint_Angle=[0;0;0;0;];
 %since no z plane exist in planar mechanism
 app.d=0;
 app.alpha=0;
 app.x=[0 0 0 0];
 app.y=[0 0 0 0];
        end

        % Value changed function: KinematicsDropDown
        function KinematicsDropDownValueChanged(app, event)
            value = app.KinematicsDropDown.Value;
             if(value=="Forward")
                 app.DH11.Editable="On";
                 app.DH21.Editable="On";
                 app.DH31.Editable="On";
                 app.DH41.Editable="On";
                 app.DH11.Value=0;
                 app.DH21.Value=0;
                 app.DH31.Value=0;
                 app.DH41.Value=0;
                 
                  app.DH13.Value=0;
                  app.DH23.Value=0;
                  app.DH33.Value=0;
                  app.DH43.Value=0;
                  
                  
                   app.Link1EditField.Visible="On";
                   app.Link2EditField.Visible="On";
                   app.Link3EditField.Visible="On";
                   app.Link4EditField.Visible="On";
                   
                   app.XLabel.Visible="off";
                   app.YLabel.Visible="off";
                   app.CoordinatesLabel.Visible="off";
                   
                   app.x1.Visible="off";
                   app.x2.Visible="off";
                   app.x3.Visible="off";
                   app.x4.Visible="off";
                   
                   app.y1.Visible="off";
                   app.y2.Visible="off";
                   app.y3.Visible="off";
                   app.y4.Visible="off";
                   
                   app.LinkLengthLabel.Visible="on";
             else
                  app.DH11.Value=0;
                  app.DH11.Editable="Off";
                  app.DH21.Value=0;
                  app.DH21.Editable="Off";
                  app.DH31.Value=0;
                  app.DH31.Editable="Off";
                  app.DH41.Value=0;
                  app.DH41.Editable="Off";
                  
                  app.DH13.Value=0;
                  app.DH23.Value=0;
                  app.DH33.Value=0;
                  app.DH43.Value=0;
                  
                  app.Link1EditField.Visible="Off";
                  app.Link2EditField.Visible="Off";
                  app.Link3EditField.Visible="Off";
                  app.Link4EditField.Visible="Off";
                 
                  app.LinkLengthLabel.Visible="off";
                 
                   app.XLabel.Visible="on";
                   app.YLabel.Visible="on";
                   app.CoordinatesLabel.Visible="on";
                   
                   app.x1.Visible="on";
                   app.x2.Visible="on";
                   app.x3.Visible="on";
                   app.x4.Visible="on";
                   
                   app.y1.Visible="on";
                   app.y2.Visible="on";
                   app.y3.Visible="on";
                   app.y4.Visible="on";
                 
             end
               
         
        end

        % Button pushed function: ProcessButton
        function ProcessButtonPushed(app, event)
            if(app.KinematicsDropDown.Value=="Forward")
                app.Main_Frame.Visible="Off";
                app.Forward_Frame.Visible="On";
                app.Inverse_Frame.Visible="Off";
                
            else
                app.Main_Frame.Visible="Off";
                app.Forward_Frame.Visible="Off";
                app.Inverse_Frame.Visible="On";
            end
        end

        % Button pushed function: Forward_BackButton
        function Forward_BackButtonPushed(app, event)
               cla(app.ForwardAxes)
                app.Forward_X.Value=0;
                app.Forward_Y.Value=0;
                app.Forward_Z.Value=0;
                app.Main_Frame.Visible="On";
                app.Forward_Frame.Visible="Off";
                app.Inverse_Frame.Visible="Off";
        end

        % Button pushed function: Inverse_BackButton
        function Inverse_BackButtonPushed(app, event)
            
           app.Inverse_theta1.Value=0;
           app.Inverse_theta2.Value=0;
           app.Inverse_theta3.Value=0;
           app.Inverse_theta4.Value=0;
           cla(app.InverseAxes);
           
            app.Main_Frame.Visible="On";
                app.Forward_Frame.Visible="Off";
                app.Inverse_Frame.Visible="Off";
        end

        % Button pushed function: CalculateForward
        function CalculateForwardButtonPushed(app, event)
                    
                        
                         syms theta_i alpha_i a_i d_i
            DH=[cosd(theta_i) -sind(theta_i)*cosd(alpha_i)  sind(theta_i)*sind(alpha_i)  a_i*cosd(theta_i);
                sind(theta_i)  cosd(theta_i)*cosd(alpha_i) -cosd(theta_i)*sind(alpha_i)  a_i*sind(theta_i);
                    0              sind(alpha_i)                  cosd(alpha_i)                d_i        ;
                    0                   0                                0                      1         ;];
            
            app.a=app.l;
            d1=app.d;
            alpha1=app.alpha;
            A=[];
            for i=1:4
                theta1=app.joint_Angle(i);
                a1=app.a(i);
                A=[A double(subs(DH,[theta_i,alpha_i,a_i,d_i],[theta1,alpha1,a1,d1]))];
            end     
            T=A(:,1:4)*A(:,5:8)*A(:,9:12)*A(:,13:16);
            app.Forward_X.Value=T(1,4);
            app.Forward_Y.Value=T(2,4);
            app.Forward_Z.Value=T(3,4);
            
            
        end

        % Value changed function: Link1EditField
        function Link1EditFieldValueChanged(app, event)
            value = app.Link1EditField.Value;
            app.l(1)=value;
            app.DH13.Value=value;            
        end

        % Value changed function: Link2EditField
        function Link2EditFieldValueChanged(app, event)
            value = app.Link2EditField.Value;
            app.l(2)=value;
            app.DH23.Value=value;
        end

        % Value changed function: Link3EditField
        function Link3EditFieldValueChanged(app, event)
            value = app.Link3EditField.Value;
            app.l(3)=value;
            app.DH33.Value=value;
        end

        % Value changed function: Link4EditField
        function Link4EditFieldValueChanged(app, event)
            value = app.Link4EditField.Value;
            app.l(4)=value;
            app.DH43.Value=value;
        end

        % Value changed function: DH11
        function DH11ValueChanged(app, event)
            value = app.DH11.Value;
            app.joint_Angle(1)=value;
        end

        % Value changed function: DH21
        function DH21ValueChanged(app, event)
            value = app.DH21.Value;
            app.joint_Angle(2)=value;
        end

        % Value changed function: DH31
        function DH31ValueChanged(app, event)
            value = app.DH31.Value;
            app.joint_Angle(3)=value;
        end

        % Value changed function: DH41
        function DH41ValueChanged(app, event)
            value = app.DH41.Value;
            app.joint_Angle(4)=value;
        end

        % Button pushed function: Forward_Plot
        function Forward_PlotButtonPushed(app, event)
            
            a1=app.l(1);
            a2=app.l(2);
            a3=app.l(3);
            a4=app.l(4);

theta1 = app.joint_Angle(1)*pi/180;
theta2 = app.joint_Angle(2)*pi/180;
theta3 = app.joint_Angle(3)*pi/180;
theta4 = app.joint_Angle(4)*pi/180;

    ax = a1*cos(theta1);
    ay = a1*sin(theta1);
    bx = ax + a2*cos(theta1+theta2);
    by = ay + a2*sin(theta1+theta2);
    cx = bx + a3*cos(theta1+theta2+theta3);
    cy = by + a3*sin(theta1+theta2+theta3);
    dx = cx + a4*cos(theta1+theta2+theta3+theta4);
    dy = cy + a4*sin(theta1+theta2+theta3+theta4);
   
     xAxisArrayXCoords = [-3,3];
     xAxisArrayYCoords = [0,0];
    
     yAxisArrayXCoords = [0,0];
     yAxisArrayYCoords = [-3,3];    
           
    link1Xcords = [0 ax];
    link1Ycords = [0 ay];
    link2Xcords = [ax bx];
    link2Ycords = [ay by];
    link3Xcords = [bx cx];
    link3Ycords = [by cy];
    link4Xcords = [cx dx];
    link4Ycords = [cy dy];   
    hold(app.ForwardAxes,"on");
    plot(app.ForwardAxes,xAxisArrayXCoords, xAxisArrayYCoords, 'r',yAxisArrayXCoords, yAxisArrayYCoords,'y');
    plot(app.ForwardAxes,link1Xcords,link1Ycords,'b',link2Xcords,link2Ycords,'c',link3Xcords,link3Ycords,'g',link4Xcords,link4Ycords,'k');
    hold(app.ForwardAxes,"off");


        end

        % Value changed function: x1
        function x1ValueChanged(app, event)
            value = app.x1.Value;
            app.x(1)=value;
           app.l(1)=sqrt(app.x(1)^2+app.y(1)^2);
            app.DH13.Value=app.l(1);
            app.l(2)=sqrt((app.x(2)-app.x(1))^2+(app.y(2)-app.y(1))^2);
            app.DH23.Value=app.l(2);
            app.l(3)=sqrt((app.x(3)-app.x(2))^2+(app.y(3)-app.y(2))^2);
            app.DH33.Value=app.l(3);
            app.l(4)=sqrt((app.x(4)-app.x(3))^2+(app.y(4)-app.y(3))^2);
            app.DH43.Value=app.l(4);
        end

        % Value changed function: x2
        function x2ValueChanged(app, event)
            value = app.x2.Value;
            app.x(2)=value;
            app.l(1)=sqrt(app.x(1)^2+app.y(1)^2);
            app.DH13.Value=app.l(1);
            app.l(2)=sqrt((app.x(2)-app.x(1))^2+(app.y(2)-app.y(1))^2);
            app.DH23.Value=app.l(2);
            app.l(3)=sqrt((app.x(3)-app.x(2))^2+(app.y(3)-app.y(2))^2);
            app.DH33.Value=app.l(3);
            app.l(4)=sqrt((app.x(4)-app.x(3))^2+(app.y(4)-app.y(3))^2);
            app.DH43.Value=app.l(4); 
        end

        % Value changed function: x3
        function x3ValueChanged(app, event)
            value = app.x3.Value;
             app.x(3)=value;
             app.l(1)=sqrt(app.x(1)^2+app.y(1)^2);
            app.DH13.Value=app.l(1);
            app.l(2)=sqrt((app.x(2)-app.x(1))^2+(app.y(2)-app.y(1))^2);
            app.DH23.Value=app.l(2);
            app.l(3)=sqrt((app.x(3)-app.x(2))^2+(app.y(3)-app.y(2))^2);
            app.DH33.Value=app.l(3);
            app.l(4)=sqrt((app.x(4)-app.x(3))^2+(app.y(4)-app.y(3))^2);
            app.DH43.Value=app.l(4);
        end

        % Value changed function: x4
        function x4ValueChanged(app, event)
            value = app.x4.Value;
             app.x(4)=value;
            app.l(1)=sqrt(app.x(1)^2+app.y(1)^2);
            app.DH13.Value=app.l(1);
            app.l(2)=sqrt((app.x(2)-app.x(1))^2+(app.y(2)-app.y(1))^2);
            app.DH23.Value=app.l(2);
            app.l(3)=sqrt((app.x(3)-app.x(2))^2+(app.y(3)-app.y(2))^2);
            app.DH33.Value=app.l(3);
            app.l(4)=sqrt((app.x(4)-app.x(3))^2+(app.y(4)-app.y(3))^2);
            app.DH43.Value=app.l(4);
        end

        % Value changed function: y1
        function y1ValueChanged(app, event)
            value = app.y1.Value;
            app.y(1)=value;
            app.l(1)=sqrt(app.x(1)^2+app.y(1)^2);
            app.DH13.Value=app.l(1);
            app.l(2)=sqrt((app.x(2)-app.x(1))^2+(app.y(2)-app.y(1))^2);
            app.DH23.Value=app.l(2);
            app.l(3)=sqrt((app.x(3)-app.x(2))^2+(app.y(3)-app.y(2))^2);
            app.DH33.Value=app.l(3);
            app.l(4)=sqrt((app.x(4)-app.x(3))^2+(app.y(4)-app.y(3))^2);
            app.DH43.Value=app.l(4);
            
        end

        % Value changed function: y2
        function y2ValueChanged(app, event)
            value = app.y2.Value;
            app.y(2)=value;
            app.l(1)=sqrt(app.x(1)^2+app.y(1)^2);
            app.DH13.Value=app.l(1);
            app.l(2)=sqrt((app.x(2)-app.x(1))^2+(app.y(2)-app.y(1))^2);
            app.DH23.Value=app.l(2);
            app.l(3)=sqrt((app.x(3)-app.x(2))^2+(app.y(3)-app.y(2))^2);
            app.DH33.Value=app.l(3);
            app.l(4)=sqrt((app.x(4)-app.x(3))^2+(app.y(4)-app.y(3))^2);
            app.DH43.Value=app.l(4);
        end

        % Value changed function: y3
        function y3ValueChanged(app, event)
            value = app.y3.Value;
            app.y(3)=value;
            app.l(1)=sqrt(app.x(1)^2+app.y(1)^2);
            app.DH13.Value=app.l(1);
            app.l(2)=sqrt((app.x(2)-app.x(1))^2+(app.y(2)-app.y(1))^2);
            app.DH23.Value=app.l(2);
            app.l(3)=sqrt((app.x(3)-app.x(2))^2+(app.y(3)-app.y(2))^2);
            app.DH33.Value=app.l(3);
            app.l(4)=sqrt((app.x(4)-app.x(3))^2+(app.y(4)-app.y(3))^2);
            app.DH43.Value=app.l(4);
        end

        % Value changed function: y4
        function y4ValueChanged(app, event)
             value = app.y4.Value;
             app.y(4)=value;
            app.l(1)=sqrt(app.x(1)^2+app.y(1)^2);
            app.DH13.Value=app.l(1);
            app.l(2)=sqrt((app.x(2)-app.x(1))^2+(app.y(2)-app.y(1))^2);
            app.DH23.Value=app.l(2);
            app.l(3)=sqrt((app.x(3)-app.x(2))^2+(app.y(3)-app.y(2))^2);
            app.DH33.Value=app.l(3);
            app.l(4)=sqrt((app.x(4)-app.x(3))^2+(app.y(4)-app.y(3))^2);
            app.DH43.Value=app.l(4);
        end

        % Button pushed function: CalculateInverse
        function CalculateInverseButtonPushed(app, event)
            x=app.x;
            y=app.y;
           % eg x = 3     2     4     5
           % eg y = 2     5     7     4
           
            l=app.l;
            theta_1=asind(y(1)/l(1));
            theta_2=asind((y(2)-y(1))/l(2))-theta_1;
            theta_3=asind((y(3)-y(2))/l(3))-theta_1-theta_2;
            theta_4=asind((y(4)-y(3))/l(4))-theta_1-theta_2-theta_3;
            app.joint_Angle(1)=theta_1;
            app.joint_Angle(2)=theta_2;
            app.joint_Angle(3)=theta_3;
            app.joint_Angle(4)=theta_4;
            
            app.Inverse_theta1.Value=theta_1;
            app.Inverse_theta2.Value=theta_2;
            app.Inverse_theta3.Value=theta_3;
            app.Inverse_theta4.Value=theta_4;
            
        end

        % Button pushed function: Inverse_Plot
        function Inverse_PlotButtonPushed(app, event)
             xAxisArrayXCoords = [-3,3];
     xAxisArrayYCoords = [0,0];
    
     yAxisArrayXCoords = [0,0];
     yAxisArrayYCoords = [-3,3];    
            x=app.x;
            y=app.y;
            theta_1=app.joint_Angle(1);
            theta_2=app.joint_Angle(2);
            theta_3=app.joint_Angle(3);
            theta_4=app.joint_Angle(4);
            
    link1Xcords = [0 x(1)];
    link1Ycords = [0 y(1)];
    link2Xcords = [x(1) x(2)];
    link2Ycords = [y(1) y(2)];
    link3Xcords = [x(2) x(3)];
    link3Ycords = [y(2) y(3)];
    link4Xcords = [x(3) x(4)];
    link4Ycords = [y(3) y(4)];   
    hold(app.InverseAxes,"on");
    plot(app.InverseAxes,xAxisArrayXCoords, xAxisArrayYCoords, 'r',yAxisArrayXCoords, yAxisArrayYCoords,'y');
      plot(app.InverseAxes,link1Xcords,link1Ycords,'b',link2Xcords,link2Ycords,'c',link3Xcords,link3Ycords,'g',link4Xcords,link4Ycords,'k');
%     line(app.InverseAxes,[x(1) 2*cosd(theta_1) ],[y(1) 2*sind(theta_1)],"color",'b',"linestyle","-.");
%     line(app.InverseAxes,[x(2) 2*cosd(theta_2) ],[y(2) 2*sind(theta_2)],"color",'c',"linestyle","-.");
%     line(app.InverseAxes,[x(3) 2*cosd(theta_3) ],[y(3) 2*sind(theta_3)],"color",'g',"linestyle","-.");
%     line(app.InverseAxes,[x(4) 2*cosd(theta_4) ],[y(4) 2*sind(theta_4)],"color",'k',"linestyle","-.");
    
    hold(app.InverseAxes,"off");


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Inverse_Frame = uipanel(app.UIFigure);
            app.InverseTitle = uitextarea(app.Inverse_Frame);
            app.InverseTitle.Editable = 'off';
            app.InverseTitle.Value = {'Inverse Kinematics'};
            app.Inverse_BackButton = uibutton(app.Inverse_Frame, 'push');
            app.Inverse_BackButton.ButtonPushedFcn = createCallbackFcn(app, @Inverse_BackButtonPushed, true);
            app.Inverse_BackButton.Text = '←  Back';
            app.CalculateInverse = uibutton(app.Inverse_Frame, 'push');
            app.CalculateInverse.ButtonPushedFcn = createCallbackFcn(app, @CalculateInverseButtonPushed, true);
            app.CalculateInverse.Text = 'Calculate';
            app.Inverse_theta2 = uieditfield(app.Inverse_Frame, 'numeric');
            app.Inverse_theta2.Editable = 'off';
            app.Inverse_theta3 = uieditfield(app.Inverse_Frame, 'numeric');
            app.Inverse_theta3.Editable = 'off';
            app.Inverse_theta4 = uieditfield(app.Inverse_Frame, 'numeric');
            app.Inverse_theta4.Editable = 'off';
            app.Joint_Angles = uitextarea(app.Inverse_Frame);
            app.Joint_Angles.Editable = 'off';
            app.Joint_Angles.Value = {'Joint Angles'};
            app.Inverse_Plot = uibutton(app.Inverse_Frame, 'push');
            app.Inverse_Plot.ButtonPushedFcn = createCallbackFcn(app, @Inverse_PlotButtonPushed, true);
            app.Inverse_Plot.Text = 'Plot';
            app.Inverse_theta1 = uieditfield(app.Inverse_Frame, 'numeric');
            app.Inverse_theta1.Editable = 'off';
            app.theta1Label_2 = uilabel(app.Inverse_Frame);
            app.theta1Label_2.Text = 'θ1';
            app.theta2Label_2 = uilabel(app.Inverse_Frame);
            app.theta2Label_2.Text = 'θ2';
            app.theta3Label_2 = uilabel(app.Inverse_Frame);
            app.theta3Label_2.Text = 'θ3';
            app.theta4Label_2 = uilabel(app.Inverse_Frame);
            app.theta4Label_2.Text = 'θ4';
            app.InverseAxes = uiaxes(app.Inverse_Frame);
            title(app.InverseAxes, 'Title')
            xlabel(app.InverseAxes, 'X')
            ylabel(app.InverseAxes, 'Y')
            zlabel(app.InverseAxes, 'Z')
            app.Forward_Frame = uipanel(app.UIFigure);
            app.Forward_BackButton = uibutton(app.Forward_Frame, 'push');
            app.Forward_BackButton.ButtonPushedFcn = createCallbackFcn(app, @Forward_BackButtonPushed, true);
            app.Forward_BackButton.Text = '←  Back';
            app.ForwardTitle = uitextarea(app.Forward_Frame);
            app.ForwardTitle.Editable = 'off';
            app.ForwardTitle.Value = {'Forward Kinematics'};
            app.CalculateForward = uibutton(app.Forward_Frame, 'push');
            app.CalculateForward.ButtonPushedFcn = createCallbackFcn(app, @CalculateForwardButtonPushed, true);
            app.CalculateForward.Text = 'Calculate';
            app.xEditFieldLabel = uilabel(app.Forward_Frame);
            app.xEditFieldLabel.Text = 'x';
            app.Forward_X = uieditfield(app.Forward_Frame, 'numeric');
            app.Forward_X.Editable = 'off';
            app.yEditFieldLabel = uilabel(app.Forward_Frame);
            app.yEditFieldLabel.Text = 'y';
            app.Forward_Y = uieditfield(app.Forward_Frame, 'numeric');
            app.Forward_Y.Editable = 'off';
            app.zEditFieldLabel = uilabel(app.Forward_Frame);
            app.zEditFieldLabel.Text = 'z';
            app.Forward_Z = uieditfield(app.Forward_Frame, 'numeric');
            app.Forward_Z.Editable = 'off';
            app.EndEffectorTitle = uitextarea(app.Forward_Frame);
            app.EndEffectorTitle.Editable = 'off';
            app.EndEffectorTitle.Value = {'End Effector '};
            app.Forward_Plot = uibutton(app.Forward_Frame, 'push');
            app.Forward_Plot.ButtonPushedFcn = createCallbackFcn(app, @Forward_PlotButtonPushed, true);
            app.Forward_Plot.Text = 'Plot';
            app.ForwardAxes = uiaxes(app.Forward_Frame);
            title(app.ForwardAxes, 'Title')
            xlabel(app.ForwardAxes, 'X')
            ylabel(app.ForwardAxes, 'Y')
            zlabel(app.ForwardAxes, 'Z')
            app.Main_Frame = uipanel(app.UIFigure);
            app.Title = uitextarea(app.Main_Frame);
            app.Title.Editable = 'off';
            app.Title.Value = {'4R Planar Mechanism'};
            app.KinematicsDropDownLabel = uilabel(app.Main_Frame);
            app.KinematicsDropDownLabel.Text = 'Kinematics';
            app.KinematicsDropDown = uidropdown(app.Main_Frame);
            app.KinematicsDropDown.Items = {'Forward', 'Inverse'};
            app.KinematicsDropDown.ValueChangedFcn = createCallbackFcn(app, @KinematicsDropDownValueChanged, true);
            app.KinematicsDropDown.Value = 'Forward';
            app.Link1EditField = uieditfield(app.Main_Frame, 'numeric');
            app.Link1EditField.ValueChangedFcn = createCallbackFcn(app, @Link1EditFieldValueChanged, true);
            app.Link2EditField = uieditfield(app.Main_Frame, 'numeric');
            app.Link2EditField.ValueChangedFcn = createCallbackFcn(app, @Link2EditFieldValueChanged, true);
            app.Link3EditField = uieditfield(app.Main_Frame, 'numeric');
            app.Link3EditField.ValueChangedFcn = createCallbackFcn(app, @Link3EditFieldValueChanged, true);
            app.Link4EditField = uieditfield(app.Main_Frame, 'numeric');
            app.Link4EditField.ValueChangedFcn = createCallbackFcn(app, @Link4EditFieldValueChanged, true);
            app.DH11 = uieditfield(app.Main_Frame, 'numeric');
            app.DH11.ValueChangedFcn = createCallbackFcn(app, @DH11ValueChanged, true);
            app.DH12 = uieditfield(app.Main_Frame, 'numeric');
            app.DH12.Editable = 'off';
            app.DH13 = uieditfield(app.Main_Frame, 'numeric');
            app.DH13.Editable = 'off';
            app.DH14 = uieditfield(app.Main_Frame, 'numeric');
            app.DH14.Editable = 'off';
            app.DH21 = uieditfield(app.Main_Frame, 'numeric');
            app.DH21.ValueChangedFcn = createCallbackFcn(app, @DH21ValueChanged, true);
            app.DH22 = uieditfield(app.Main_Frame, 'numeric');
            app.DH22.Editable = 'off';
            app.DH23 = uieditfield(app.Main_Frame, 'numeric');
            app.DH23.Editable = 'off';
            app.DH24 = uieditfield(app.Main_Frame, 'numeric');
            app.DH24.Editable = 'off';
            app.DH31 = uieditfield(app.Main_Frame, 'numeric');
            app.DH31.ValueChangedFcn = createCallbackFcn(app, @DH31ValueChanged, true);
            app.DH32 = uieditfield(app.Main_Frame, 'numeric');
            app.DH32.Editable = 'off';
            app.DH33 = uieditfield(app.Main_Frame, 'numeric');
            app.DH33.Editable = 'off';
            app.DH34 = uieditfield(app.Main_Frame, 'numeric');
            app.DH34.Editable = 'off';
            app.DH41 = uieditfield(app.Main_Frame, 'numeric');
            app.DH41.ValueChangedFcn = createCallbackFcn(app, @DH41ValueChanged, true);
            app.DH42 = uieditfield(app.Main_Frame, 'numeric');
            app.DH42.Editable = 'off';
            app.DH43 = uieditfield(app.Main_Frame, 'numeric');
            app.DH43.Editable = 'off';
            app.DH44 = uieditfield(app.Main_Frame, 'numeric');
            app.DH44.Editable = 'off';
            app.Label_theta = uilabel(app.Main_Frame);
            app.Label_theta.Text = 'θ';
            app.Label_d = uilabel(app.Main_Frame);
            app.Label_d.Text = 'd';
            app.Label_a = uilabel(app.Main_Frame);
            app.Label_a.Text = 'a';
            app.Label_alpha = uilabel(app.Main_Frame);
            app.Label_alpha.Text = 'α';
            app.Link1Label_2 = uilabel(app.Main_Frame);
            app.Link1Label_2.Text = 'Link 1';
            app.Link2Label_2 = uilabel(app.Main_Frame);
            app.Link2Label_2.Text = 'Link 2';
            app.Link3Label_2 = uilabel(app.Main_Frame);
            app.Link3Label_2.Text = 'Link 3';
            app.Link4Label_2 = uilabel(app.Main_Frame);
            app.Link4Label_2.Text = 'Link 4';
            app.ProcessButton = uibutton(app.Main_Frame, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.ProcessButton.Text = {'Process'; ''};
            app.LinkLengthLabel = uilabel(app.Main_Frame);
            app.LinkLengthLabel.Text = 'Link Length ';
            app.DHTableLabel = uilabel(app.Main_Frame);
            app.DHTableLabel.Text = 'DH Table';
            app.Link1Label_3 = uilabel(app.Main_Frame);
            app.Link1Label_3.Text = 'Link 1';
            app.Link2Label_3 = uilabel(app.Main_Frame);
            app.Link2Label_3.Text = 'Link 2';
            app.Link3Label_3 = uilabel(app.Main_Frame);
            app.Link3Label_3.Text = 'Link 3';
            app.Link4Label_3 = uilabel(app.Main_Frame);
            app.Link4Label_3.Text = 'Link 4';
            app.x2 = uieditfield(app.Main_Frame, 'numeric');
            app.x2.ValueChangedFcn = createCallbackFcn(app, @x2ValueChanged, true);
            app.x2.Visible = 'off';
            app.y2 = uieditfield(app.Main_Frame, 'numeric');
            app.y2.ValueChangedFcn = createCallbackFcn(app, @y2ValueChanged, true);
            app.y2.Visible = 'off';
            app.x4 = uieditfield(app.Main_Frame, 'numeric');
            app.x4.ValueChangedFcn = createCallbackFcn(app, @x4ValueChanged, true);
            app.x4.Visible = 'off';
            app.y4 = uieditfield(app.Main_Frame, 'numeric');
            app.y4.ValueChangedFcn = createCallbackFcn(app, @y4ValueChanged, true);
            app.y4.Visible = 'off';
            app.x3 = uieditfield(app.Main_Frame, 'numeric');
            app.x3.ValueChangedFcn = createCallbackFcn(app, @x3ValueChanged, true);
            app.x3.Visible = 'off';
            app.y3 = uieditfield(app.Main_Frame, 'numeric');
            app.y3.ValueChangedFcn = createCallbackFcn(app, @y3ValueChanged, true);
            app.y3.Visible = 'off';
            app.CoordinatesLabel = uilabel(app.Main_Frame);
            app.CoordinatesLabel.Visible = 'off';
            app.CoordinatesLabel.Text = 'Co-ordinates';
            app.XLabel = uilabel(app.Main_Frame);
            app.XLabel.Visible = 'off';
            app.XLabel.Text = 'X';
            app.YLabel = uilabel(app.Main_Frame);
            app.YLabel.Visible = 'off';
            app.YLabel.Text = 'Y';
            app.x1 = uieditfield(app.Main_Frame, 'numeric');
            app.x1.ValueChangedFcn = createCallbackFcn(app, @x1ValueChanged, true);
            app.x1.Visible = 'off';
            app.y1 = uieditfield(app.Main_Frame, 'numeric');
            app.y1.ValueChangedFcn = createCallbackFcn(app, @y1ValueChanged, true);
            app.y1.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Rob_4R_Planar_Mechanism

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end